classdef LabGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        soundButton_4            matlab.ui.control.Button
        soundButton_3            matlab.ui.control.Button
        soundButton_2            matlab.ui.control.Button
        EllipButton_2            matlab.ui.control.Button
        ButterWorthButton        matlab.ui.control.Button
        KaiserWindowButton       matlab.ui.control.Button
        soundButton              matlab.ui.control.Button
        EllipFitterButton        matlab.ui.control.Button
        ButterworthFitterButton  matlab.ui.control.Button
        KaiserFitterButton       matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        UIAxes4                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)
            
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            % 时间轴
            filename = 'keyboad-typing.wav';

            [audio, fs] = audioread(filename); 
           
            % 时间轴
            t = (0:(length(audio)-1))*(1/fs);

            audio_fft = fft(audio);
        
            freqfft = (0:(length(audio_fft)/2-1))*(fs/length(audio_fft));

            plot(app.UIAxes,t, audio);
            
            plot(app.UIAxes2,freqfft, abs(audio_fft(1:length(audio_fft)/2)));

        end

        % Button pushed function: KaiserFitterButton
        function KaiserFitterButtonPushed(app, event)
            % 时间轴
            filename = 'keyboad-typing.wav';

            [audio, fs] = audioread(filename); 
           
            % 时间轴
            t = (0:(length(audio)-1))*(1/fs);

            % kaiser
            fp1=1000; fs1=1200; rp1=1; rs1=100;
            N1 = 48;
            b = fir1(N1,fp1/(fs/2),'low',kaiser(N1+1)); 


            fir_audio = fftfilt(b,audio);

            fir_audio_fft = fft(fir_audio);
            freqfft = (0:(length(fir_audio_fft)/2-1))*(fs/length(fir_audio_fft));

            plot(app.UIAxes3,t, fir_audio);
            
            plot(app.UIAxes4,freqfft, abs(fir_audio_fft(1:length(fir_audio_fft)/2)));

        end

        % Button pushed function: ButterworthFitterButton
        function ButterworthFitterButtonPushed(app, event)
            % 时间轴
            filename = 'keyboad-typing.wav';

            [audio, fs] = audioread(filename); 
           
            % 时间轴
            t = (0:(length(audio)-1))*(1/fs);

            T = 5.034;
            fp2=4800; fs2=5000; rp2=1; rs2=100;  
            
            wp2 = fp2/(fs/2); ws2 = fs2/(fs/2);
            
            [N2,wc2] = buttord(wp2,ws2,rp2,rs2);
            [B2, A2] = butter(N2, wc2, 'high');
            [Bz, Az] = bilinear(B2, A2, fs);


            iir1_audio = filter(Bz,Az,audio);

            iir1_audio_fft = fft(iir1_audio);
            freqfft = (0:(length(iir1_audio_fft)/2-1))*(fs/length(iir1_audio_fft));

            plot(app.UIAxes3,t, iir1_audio);
            
            plot(app.UIAxes4,freqfft, abs(iir1_audio_fft(1:length(iir1_audio_fft)/2)));
        end

        % Button pushed function: soundButton_4
        function soundButton_4Pushed(app, event)
            filename = 'keyboad-typing.wav';

            [audio, fs] = audioread(filename); 

            sound(audio,fs);
        end

        % Button pushed function: soundButton
        function soundButtonPushed(app, event)
            
            filename = 'keyboad-typing.wav';

            [audio, fs] = audioread(filename); 

            t = (0:(length(audio)-1))*(1/fs);

            % kaiser
            fp1=1000; fs1=1200; rp1=1; rs1=100;
            N1 = 48;
            b = fir1(N1,fp1/(fs/2),'low',kaiser(N1+1));

            fir_audio = fftfilt(b,audio);

            sound(fir_audio,fs);

        end

        % Button pushed function: KaiserWindowButton
        function KaiserWindowButtonPushed(app, event)

            filename = 'keyboad-typing.wav';
            
            [audio, fs] = audioread(filename); 
            
            t = (0:(length(audio)-1))*(1/fs);
            
            
            fp1=1000; fs1=1200; rp1=1; rs1=100;
            N1 = 48;
            b = fir1(N1,fp1/(fs/2),'low',kaiser(N1+1));  
            
            [h,f] = freqz(b, 1, 1024, fs);
            
            plot(app.UIAxes3,f,abs(h));
            plot(app.UIAxes4,f,angle(h));


        end

        % Button pushed function: ButterWorthButton
        function ButterWorthButtonPushed(app, event)
            
            filename = 'keyboad-typing.wav';
            
            [audio, fs] = audioread(filename); 
            
            t = (0:(length(audio)-1))*(1/fs);
            
            T = 5.034
            fp2=4800; fs2=5000; rp2=1; rs2=100;  
            
            wp2 = fp2/(fs/2); ws2 = fs2/(fs/2);
            
            [N2,wc2] = buttord(wp2,ws2,rp2,rs2);
            [B2, A2] = butter(N2, wc2, 'high');
            [Bz, Az] = bilinear(B2, A2, fs);
            
            %freqz(Bz, Az, 1024, fs);
            [h,f] = freqz(Bz, Az, 1024, fs);
            
            
            plot(app.UIAxes3,f,abs(h));
         
            plot(app.UIAxes4,f,angle(h));


        end

        % Button pushed function: EllipButton_2
        function EllipButton_2Pushed(app, event)
                        
            filename = 'keyboad-typing.wav';
            
            [audio, fs] = audioread(filename); 
            
            t = (0:(length(audio)-1))*(1/fs);


            fb1=1200; fb2=3000; fc1=1000; fc2=3200;
            wp3 = [2*fb1/fs, 2*fb2/fs];  ws3 = [2*fc1/fs, 2*fc2/fs]     %归一化
            rp3=1; rs3=100;
            
            [N3,wpo] = ellipord(wp3,ws3,rp3,rs3);
            [B3,A3] = ellip(N3,rp3,rs3,wpo);
            
            
            %grid on;
            [h,f] = freqz(B3, A3, 1024, fs);
            
      
            plot(app.UIAxes3,f,abs(h));
     
            plot(app.UIAxes4,f,angle(h));


        end

        % Button pushed function: soundButton_2
        function soundButton_2Pushed(app, event)
            filename = 'keyboad-typing.wav';
            
            [audio, fs] = audioread(filename); 
            
            t = (0:(length(audio)-1))*(1/fs);
            
            T = 5.034
            fp2=4800; fs2=5000; rp2=1; rs2=100;  
            
            wp2 = fp2/(fs/2); ws2 = fs2/(fs/2);
            
            [N2,wc2] = buttord(wp2,ws2,rp2,rs2);
            [B2, A2] = butter(N2, wc2, 'high');
            [Bz, Az] = bilinear(B2, A2, fs);


            iir1_audio = filter(Bz,Az,audio);


            sound(iir1_audio,fs)


        end

        % Button pushed function: EllipFitterButton
        function EllipFitterButtonPushed(app, event)
            
            filename = 'keyboad-typing.wav';
            
            [audio, fs] = audioread(filename); 
            
            t = (0:(length(audio)-1))*(1/fs);
            
            
            fb1=1200; fb2=3000; fc1=1000; fc2=3200;
            wp3 = [2*fb1/fs, 2*fb2/fs];  ws3 = [2*fc1/fs, 2*fc2/fs]     %归一化
            rp3=1; rs3=100;
            
            [N3,wpo] = ellipord(wp3,ws3,rp3,rs3);
            [B3,A3] = ellip(N3,rp3,rs3,wpo);

            iir2_audio = filter(B3,A3,audio);

            iir2_audio_fft = fft(iir2_audio);
            freqfft = (0:(length(iir2_audio_fft)/2-1))*(fs/length(iir2_audio_fft));

            plot(app.UIAxes3,t, iir2_audio);
            
            plot(app.UIAxes4,freqfft, abs(iir2_audio_fft(1:length(iir2_audio_fft)/2)))


        end

        % Button pushed function: soundButton_3
        function soundButton_3Pushed(app, event)
            filename = 'keyboad-typing.wav';
            
            [audio, fs] = audioread(filename); 
            
            t = (0:(length(audio)-1))*(1/fs);
            
            
            fb1=1200; fb2=3000; fc1=1000; fc2=3200;
            wp3 = [2*fb1/fs, 2*fb2/fs];  ws3 = [2*fc1/fs, 2*fc2/fs]     %归一化
            rp3=1; rs3=100;
            
            [N3,wpo] = ellipord(wp3,ws3,rp3,rs3);
            [B3,A3] = ellip(N3,rp3,rs3,wpo);

            iir2_audio = filter(B3,A3,audio);

            sound(iir2_audio,fs)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'Frequency (Hz)')
            ylabel(app.UIAxes2, '|X(f)|')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, 'Time(s)')
            ylabel(app.UIAxes3, 'Amplitude')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes4, 'Frequency(Hz)')
            ylabel(app.UIAxes4, '|X(f)|')
            zlabel(app.UIAxes4, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Text = '时域和频域(未滤波)';
            app.KaiserFitterButton = uibutton(app.UIFigure, 'push');
            app.KaiserFitterButton.ButtonPushedFcn = createCallbackFcn(app, @KaiserFitterButtonPushed, true);
            app.KaiserFitterButton.Text = 'KaiserFitter';
            app.ButterworthFitterButton = uibutton(app.UIFigure, 'push');
            app.ButterworthFitterButton.ButtonPushedFcn = createCallbackFcn(app, @ButterworthFitterButtonPushed, true);
            app.ButterworthFitterButton.Text = 'ButterworthFitter';
            app.EllipFitterButton = uibutton(app.UIFigure, 'push');
            app.EllipFitterButton.ButtonPushedFcn = createCallbackFcn(app, @EllipFitterButtonPushed, true);
            app.EllipFitterButton.Text = 'EllipFitter';
            app.soundButton = uibutton(app.UIFigure, 'push');
            app.soundButton.ButtonPushedFcn = createCallbackFcn(app, @soundButtonPushed, true);
            app.soundButton.Text = 'sound';
            app.KaiserWindowButton = uibutton(app.UIFigure, 'push');
            app.KaiserWindowButton.ButtonPushedFcn = createCallbackFcn(app, @KaiserWindowButtonPushed, true);
            app.KaiserWindowButton.Text = 'KaiserWindow';
            app.ButterWorthButton = uibutton(app.UIFigure, 'push');
            app.ButterWorthButton.ButtonPushedFcn = createCallbackFcn(app, @ButterWorthButtonPushed, true);
            app.ButterWorthButton.Text = 'ButterWorth';
            app.EllipButton_2 = uibutton(app.UIFigure, 'push');
            app.EllipButton_2.ButtonPushedFcn = createCallbackFcn(app, @EllipButton_2Pushed, true);
            app.EllipButton_2.Text = 'Ellip';
            app.soundButton_2 = uibutton(app.UIFigure, 'push');
            app.soundButton_2.ButtonPushedFcn = createCallbackFcn(app, @soundButton_2Pushed, true);
            app.soundButton_2.Text = 'sound';
            app.soundButton_3 = uibutton(app.UIFigure, 'push');
            app.soundButton_3.ButtonPushedFcn = createCallbackFcn(app, @soundButton_3Pushed, true);
            app.soundButton_3.Text = 'sound';
            app.soundButton_4 = uibutton(app.UIFigure, 'push');
            app.soundButton_4.ButtonPushedFcn = createCallbackFcn(app, @soundButton_4Pushed, true);
            app.soundButton_4.Text = 'sound';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LabGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end