classdef speechDSP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        soundButton_7         matlab.ui.control.Button
        soundButton_6         matlab.ui.control.Button
        soundButton_5         matlab.ui.control.Button
        soundButton_4         matlab.ui.control.Button
        soundButton_3         matlab.ui.control.Button
        soundButton_2         matlab.ui.control.Button
        BandpassIIRButton_2   matlab.ui.control.Button
        BandpassFIRButton_2   matlab.ui.control.Button
        HighpassIIRButton_2   matlab.ui.control.Button
        HighpassFIRButton_2   matlab.ui.control.Button
        LowpassIIRButton_2    matlab.ui.control.Button
        LowpassFIRButton_2    matlab.ui.control.Button
        BandpassIIRButton     matlab.ui.control.Button
        BandpassFIRButton     matlab.ui.control.Button
        HighpassIIRButton     matlab.ui.control.Button
        HighpassFIRButton     matlab.ui.control.Button
        LowpassIIRButton      matlab.ui.control.Button
        LowpassFIRButton      matlab.ui.control.Button
        soundButton           matlab.ui.control.Button
        originalsignalButton  matlab.ui.control.Button
        UIAxes_6              matlab.ui.control.UIAxes
        UIAxes_5              matlab.ui.control.UIAxes
        UIAxes_3              matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: originalsignalButton
        function originalsignalButtonPushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);
            
            audio_fft = fft(audio);

            freqfft = (0:(length(audio_fft)/2-1))*(fs/length(audio_fft));
            
            plot(app.UIAxes,t, audio);

            title(app.UIAxes, 'Time-Domain');
            xlabel(app.UIAxes, 't(s)');
            ylabel(app.UIAxes, 'Amplitude');
            grid(app.UIAxes, 'on');


            plot(app.UIAxes_2,freqfft, abs(audio_fft(1:length(audio_fft)/2)));

            title(app.UIAxes_2, 'Frequency-Domain');
            xlabel(app.UIAxes_2, 'Frequency');
            ylabel(app.UIAxes_2, '|X(f)|');
            grid(app.UIAxes_2, 'on');

        end

        % Button pushed function: soundButton
        function soundButtonPushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            sound(audio);
        end

        % Button pushed function: LowpassFIRButton
        function LowpassFIRButtonPushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);
            
            % 利用设计低通滤波器
            % fp1=1000 fs1=1200 (Hz)
            % rp1=1 rs1=100 (dB)
            
            fp1=1000; fs1=1200; rp1=1; rs1=100;
            
            % fir1设计
            N = 48;
            b = fir1(N,fp1/(fs/2),'low',kaiser(N+1));  

            [H,w] = freqz(b, 1, 1024, fs);
            mag = abs(H);
            db = 20*log10((mag+eps)/max(mag));
            plot(app.UIAxes_3,w/pi,db);

            title(app.UIAxes_3, 'Filter Frequency Response');
            xlabel(app.UIAxes_3, 'Frequency');
            ylabel(app.UIAxes_3, 'Magnitude (dB)');
            grid(app.UIAxes_3, 'on');

        end

        % Button pushed function: HighpassFIRButton
        function HighpassFIRButtonPushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);

            fp2=4800; fs2=5000; rp2=1; rs2=100;  

            % fir1设计
            N = 48;
            b2 = fir1(N,fp2/(fs/2),'high',kaiser(N+1));  
           
            [H,w] = freqz(b2, 1, 1024, fs);
            mag = abs(H);
            db = 20*log10((mag+eps)/max(mag));
            plot(app.UIAxes_3,w/pi,db);

            title(app.UIAxes_3, 'Filter Frequency Response');
            xlabel(app.UIAxes_3, 'Frequency');
            ylabel(app.UIAxes_3, 'Magnitude (dB)');
            grid(app.UIAxes_3, 'on');

        end

        % Button pushed function: HighpassIIRButton
        function HighpassIIRButtonPushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);

            fp2=4800; fs2=5000; rp2=1; rs2=100;  

            wp2=2*pi*fp2/(fs/4);
            ws2=2*pi*fs2/(fs/4);
            
            [N2,wpo2]=ellipord(wp2,ws2,rp2,rs2,'s');
            [B2,A2]=ellip(N2,rp2,rs2,wpo2,'high','s');
            [Bz2,Az2]=bilinear(B2,A2,1);

            [H,w] = freqz(Bz2,Az2);
            mag = abs(H);
            db = 20*log10((mag+eps)/max(mag));
            plot(app.UIAxes_3,w/pi,db);

            title(app.UIAxes_3, 'Filter Frequency Response');
            xlabel(app.UIAxes_3, 'Frequency');
            ylabel(app.UIAxes_3, 'Magnitude (dB)');
            grid(app.UIAxes_3, 'on');
        end

        % Button pushed function: LowpassIIRButton
        function LowpassIIRButtonPushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);
            
            % 利用设计低通滤波器
            % fp1=1000 fs1=1200 (Hz)
            % rp1=1 rs1=100 (dB)
            
            fp1=1000; fs1=1200; rp1=1; rs1=100;
            
            wp1=2*pi*fp1/fs;
            ws1=2*pi*fs1/fs;
            [N1,wpo1]=ellipord(wp1,ws1,rp1,rs1,'s');
            [B1, A1]=ellip(N1,rp1,rs1,wpo1,'s');
            [Bz1,Az1]=bilinear(B1, A1,1);
            
            [H,w] = freqz(Bz1,Az1);
            mag = abs(H);
            db = 20*log10((mag+eps)/max(mag));
            plot(app.UIAxes_3, w/pi, db);

            title(app.UIAxes_3, 'Filter Frequency Response');
            xlabel(app.UIAxes_3, 'Frequency');
            ylabel(app.UIAxes_3, 'Magnitude (dB)');
            grid(app.UIAxes_3, 'on');

        end

        % Button pushed function: BandpassFIRButton
        function BandpassFIRButtonPushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);

            fb1=1200; fb2=3000; fc1=1000; fc2=3200;
            rp3=1; rs3=100;
      
            % fir1设计
            N = 48;
            b3 = fir1(N,[fb1/(fs/2) fb2/(fs/2)],'bandpass',kaiser(N+1));  

            [H,w] = freqz(b3, 1, 1024, fs);
            mag = abs(H);
            db = 20*log10((mag+eps)/max(mag));
            plot(app.UIAxes_3,w/pi,db);

            title(app.UIAxes_3, 'Filter Frequency Response');
            xlabel(app.UIAxes_3, 'Frequency');
            ylabel(app.UIAxes_3, 'Magnitude (dB)');
            grid(app.UIAxes_3, 'on');


        end

        % Button pushed function: BandpassIIRButton
        function BandpassIIRButtonPushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);

            fb1=1200; fb2=3000; fc1=1000; fc2=3200;
            rp3=1; rs3=100;


            % ellip设计
            wp3 = [2*pi*fb1/(fs/4), 2*pi*fb2/(fs/4)];  
            ws3 = [2*pi*fc1/(fs/4), 2*pi*fc2/(fs/4)]    
            
            [N3,wpo3]=ellipord(wp3,ws3,rp3,rs3,'s');
            [B3,A3]=ellip(N3,rp3,rs3,wpo3,'s');
            [Bz3,Az3]=bilinear(B3,A3,1);
           
            [H,w] = freqz(Bz3,Az3);
            mag = abs(H);
            db = 20*log10((mag+eps)/max(mag));
            plot(app.UIAxes_3,w/pi,db);


            title(app.UIAxes_3, 'Filter Frequency Response');
            xlabel(app.UIAxes_3, 'Frequency');
            ylabel(app.UIAxes_3, 'Magnitude (dB)');
            grid(app.UIAxes_3, 'on');
        end

        % Button pushed function: LowpassFIRButton_2
        function LowpassFIRButton_2Pushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);
            
            % 利用设计低通滤波器
            % fp1=1000 fs1=1200 (Hz)
            % rp1=1 rs1=100 (dB)
            
            fp1=1000; fs1=1200; rp1=1; rs1=100;
            
            % fir1设计
            N = 48;
            b = fir1(N,fp1/(fs/2),'low',kaiser(N+1)); 

            fir1_audio = fftfilt(b,audio);

            audio_fft = fft(fir1_audio);

            freqfft = (0:(length(audio_fft)/2-1))*(fs/length(audio_fft));

            plot(app.UIAxes_5,t, fir1_audio);
            title(app.UIAxes_5, 'Time-Domain');
            xlabel(app.UIAxes_5, 't(s)');
            ylabel(app.UIAxes_5, 'Amplitude');
            grid(app.UIAxes_5, 'on');

            
            plot(app.UIAxes_6,freqfft, abs(audio_fft(1:length(audio_fft)/2)));

            title(app.UIAxes_6, 'Frequency-Domain');
            xlabel(app.UIAxes_6, 'Frequency');
            ylabel(app.UIAxes_6, '|X(f)|');
            grid(app.UIAxes_6, 'on');

        end

        % Button pushed function: LowpassIIRButton_2
        function LowpassIIRButton_2Pushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);
            
            % 利用设计低通滤波器
            % fp1=1000 fs1=1200 (Hz)
            % rp1=1 rs1=100 (dB)
            
            fp1=1000; fs1=1200; rp1=1; rs1=100;
            
            wp1=2*pi*fp1/fs;
            ws1=2*pi*fs1/fs;
            [N1,wpo1]=ellipord(wp1,ws1,rp1,rs1,'s');
            [B1, A1]=ellip(N1,rp1,rs1,wpo1,'s');
            [Bz1,Az1]=bilinear(B1, A1,1);
            
            iir1_audio = filter(Bz1,Az1,audio);


            audio_fft = fft(iir1_audio);

            freqfft = (0:(length(audio_fft)/2-1))*(fs/length(audio_fft));

            plot(app.UIAxes_5,t, iir1_audio);

            title(app.UIAxes_5, 'Time-Domain');
            xlabel(app.UIAxes_5, 't(s)');
            ylabel(app.UIAxes_5, 'Amplitude');
            grid(app.UIAxes_5, 'on');

            plot(app.UIAxes_6,freqfft, abs(audio_fft(1:length(audio_fft)/2)));

            title(app.UIAxes_6, 'Frequency-Domain');
            xlabel(app.UIAxes_6, 'Frequency');
            ylabel(app.UIAxes_6, '|X(f)|');
            grid(app.UIAxes_6, 'on');


        end

        % Button pushed function: HighpassFIRButton_2
        function HighpassFIRButton_2Pushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);

            fp2=4800; fs2=5000; rp2=1; rs2=100;  

            % fir1设计
            N = 48;
            b2 = fir1(N,fp2/(fs/2),'high',kaiser(N+1));  

            fir2_audio = fftfilt(b2,audio);

            audio_fft = fft(fir2_audio);

            freqfft = (0:(length(audio_fft)/2-1))*(fs/length(audio_fft));

            plot(app.UIAxes_5,t, fir2_audio);

            title(app.UIAxes_5, 'Time-Domain');
            xlabel(app.UIAxes_5, 't(s)');
            ylabel(app.UIAxes_5, 'Amplitude');
            grid(app.UIAxes_5, 'on');

            plot(app.UIAxes_6,freqfft, abs(audio_fft(1:length(audio_fft)/2)));

            title(app.UIAxes_6, 'Frequency-Domain');
            xlabel(app.UIAxes_6, 'Frequency');
            ylabel(app.UIAxes_6, '|X(f)|');
            grid(app.UIAxes_6, 'on');


        end

        % Button pushed function: HighpassIIRButton_2
        function HighpassIIRButton_2Pushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);

            fp2=4800; fs2=5000; rp2=1; rs2=100;  

            wp2=2*pi*fp2/(fs/4);
            ws2=2*pi*fs2/(fs/4);
            
            [N2,wpo2]=ellipord(wp2,ws2,rp2,rs2,'s');
            [B2,A2]=ellip(N2,rp2,rs2,wpo2,'high','s');
            [Bz2,Az2]=bilinear(B2,A2,1);


            iir2_audio = filter(Bz2,Az2,audio);


            audio_fft = fft(iir2_audio);

            freqfft = (0:(length(audio_fft)/2-1))*(fs/length(audio_fft));

            plot(app.UIAxes_5,t, iir2_audio);

            title(app.UIAxes_5, 'Time-Domain');
            xlabel(app.UIAxes_5, 't(s)');
            ylabel(app.UIAxes_5, 'Amplitude');
            grid(app.UIAxes_5, 'on');

            plot(app.UIAxes_6,freqfft, abs(audio_fft(1:length(audio_fft)/2)));

            title(app.UIAxes_6, 'Frequency-Domain');
            xlabel(app.UIAxes_6, 'Frequency');
            ylabel(app.UIAxes_6, '|X(f)|');
            grid(app.UIAxes_6, 'on');

        end

        % Button pushed function: BandpassFIRButton_2
        function BandpassFIRButton_2Pushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);

            fb1=1200; fb2=3000; fc1=1000; fc2=3200;
            rp3=1; rs3=100;
      
            % fir1设计
            N = 48;
            b3 = fir1(N,[fb1/(fs/2) fb2/(fs/2)],'bandpass',kaiser(N+1)); 

            fir3_audio = fftfilt(b3,audio);

            audio_fft = fft(fir3_audio);

            freqfft = (0:(length(audio_fft)/2-1))*(fs/length(audio_fft));

            plot(app.UIAxes_5,t, fir3_audio);

            title(app.UIAxes_5, 'Time-Domain');
            xlabel(app.UIAxes_5, 't(s)');
            ylabel(app.UIAxes_5, 'Amplitude');
            grid(app.UIAxes_5, 'on');

            plot(app.UIAxes_6,freqfft, abs(audio_fft(1:length(audio_fft)/2)));
           
            title(app.UIAxes_6, 'Frequency-Domain');
            xlabel(app.UIAxes_6, 'Frequency');
            ylabel(app.UIAxes_6, '|X(f)|');
            grid(app.UIAxes_6, 'on');

        end

        % Button pushed function: BandpassIIRButton_2
        function BandpassIIRButton_2Pushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);

            fb1=1200; fb2=3000; fc1=1000; fc2=3200;
            rp3=1; rs3=100;

            % ellip设计
            wp3 = [2*pi*fb1/(fs/4), 2*pi*fb2/(fs/4)];  
            ws3 = [2*pi*fc1/(fs/4), 2*pi*fc2/(fs/4)]    
            
            [N3,wpo3]=ellipord(wp3,ws3,rp3,rs3,'s');
            [B3,A3]=ellip(N3,rp3,rs3,wpo3,'s');
            [Bz3,Az3]=bilinear(B3,A3,1);

            iir3_audio = filter(Bz3,Az3,audio);


            audio_fft = fft(iir3_audio);

            freqfft = (0:(length(audio_fft)/2-1))*(fs/length(audio_fft));

            plot(app.UIAxes_5,t, iir3_audio);
            
            title(app.UIAxes_5, 'Time-Domain');
            xlabel(app.UIAxes_5, 't(s)');
            ylabel(app.UIAxes_5, 'Amplitude');
            grid(app.UIAxes_5, 'on');

            plot(app.UIAxes_6,freqfft, abs(audio_fft(1:length(audio_fft)/2)));

            title(app.UIAxes_6, 'Frequency-Domain');
            xlabel(app.UIAxes_6, 'Frequency');
            ylabel(app.UIAxes_6, '|X(f)|');
            grid(app.UIAxes_6, 'on');


        end

        % Button pushed function: soundButton_2
        function soundButton_2Pushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);
            
            % 利用设计低通滤波器
            % fp1=1000 fs1=1200 (Hz)
            % rp1=1 rs1=100 (dB)
            
            fp1=1000; fs1=1200; rp1=1; rs1=100;
            
            % fir1设计
            N = 48;
            b = fir1(N,fp1/(fs/2),'low',kaiser(N+1)); 

            fir1_audio = fftfilt(b,audio);

            sound(fir1_audio);

        end

        % Button pushed function: soundButton_3
        function soundButton_3Pushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);
            
            % 利用设计低通滤波器
            % fp1=1000 fs1=1200 (Hz)
            % rp1=1 rs1=100 (dB)
            
            fp1=1000; fs1=1200; rp1=1; rs1=100;
            
            wp1=2*pi*fp1/fs;
            ws1=2*pi*fs1/fs;
            [N1,wpo1]=ellipord(wp1,ws1,rp1,rs1,'s');
            [B1, A1]=ellip(N1,rp1,rs1,wpo1,'s');
            [Bz1,Az1]=bilinear(B1, A1,1);
            
            iir1_audio = filter(Bz1,Az1,audio);
            sound(iir1_audio);
        end

        % Button pushed function: soundButton_4
        function soundButton_4Pushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);

            fp2=4800; fs2=5000; rp2=1; rs2=100;  

            % fir1设计
            N = 48;
            b2 = fir1(N,fp2/(fs/2),'high',kaiser(N+1));  

            fir2_audio = fftfilt(b2,audio);
            sound(fir2_audio);
        end

        % Button pushed function: soundButton_5
        function soundButton_5Pushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);

            fp2=4800; fs2=5000; rp2=1; rs2=100;  

            wp2=2*pi*fp2/(fs/4);
            ws2=2*pi*fs2/(fs/4);
            
            [N2,wpo2]=ellipord(wp2,ws2,rp2,rs2,'s');
            [B2,A2]=ellip(N2,rp2,rs2,wpo2,'high','s');
            [Bz2,Az2]=bilinear(B2,A2,1);


            iir2_audio = filter(Bz2,Az2,audio);
            sound(iir2_audio)
        end

        % Button pushed function: soundButton_6
        function soundButton_6Pushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);

            fb1=1200; fb2=3000; fc1=1000; fc2=3200;
            rp3=1; rs3=100;
      
            % fir1设计
            N = 48;
            b3 = fir1(N,[fb1/(fs/2) fb2/(fs/2)],'bandpass',kaiser(N+1)); 

            fir3_audio = fftfilt(b3,audio);
            sound(fir3_audio);
        end

        % Button pushed function: soundButton_7
        function soundButton_7Pushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);

            fb1=1200; fb2=3000; fc1=1000; fc2=3200;
            rp3=1; rs3=100;

            % ellip设计
            wp3 = [2*pi*fb1/(fs/4), 2*pi*fb2/(fs/4)];  
            ws3 = [2*pi*fc1/(fs/4), 2*pi*fc2/(fs/4)]    
            
            [N3,wpo3]=ellipord(wp3,ws3,rp3,rs3,'s');
            [B3,A3]=ellip(N3,rp3,rs3,wpo3,'s');
            [Bz3,Az3]=bilinear(B3,A3,1);

            iir3_audio = filter(Bz3,Az3,audio);
            sound(iir3_audio);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.originalsignalButton = uibutton(app.UIFigure, 'push');
            app.originalsignalButton.ButtonPushedFcn = createCallbackFcn(app, @originalsignalButtonPushed, true);
            app.originalsignalButton.Text = 'original signal';
            app.soundButton = uibutton(app.UIFigure, 'push');
            app.soundButton.ButtonPushedFcn = createCallbackFcn(app, @soundButtonPushed, true);
            app.soundButton.Text = 'sound';
            app.LowpassFIRButton = uibutton(app.UIFigure, 'push');
            app.LowpassFIRButton.ButtonPushedFcn = createCallbackFcn(app, @LowpassFIRButtonPushed, true);
            app.LowpassFIRButton.Text = 'Lowpass FIR';
            app.LowpassIIRButton = uibutton(app.UIFigure, 'push');
            app.LowpassIIRButton.ButtonPushedFcn = createCallbackFcn(app, @LowpassIIRButtonPushed, true);
            app.LowpassIIRButton.Text = 'Lowpass IIR';
            app.HighpassFIRButton = uibutton(app.UIFigure, 'push');
            app.HighpassFIRButton.ButtonPushedFcn = createCallbackFcn(app, @HighpassFIRButtonPushed, true);
            app.HighpassFIRButton.Text = 'Highpass FIR';
            app.HighpassIIRButton = uibutton(app.UIFigure, 'push');
            app.HighpassIIRButton.ButtonPushedFcn = createCallbackFcn(app, @HighpassIIRButtonPushed, true);
            app.HighpassIIRButton.Text = 'Highpass IIR';
            app.BandpassFIRButton = uibutton(app.UIFigure, 'push');
            app.BandpassFIRButton.ButtonPushedFcn = createCallbackFcn(app, @BandpassFIRButtonPushed, true);
            app.BandpassFIRButton.Text = 'Bandpass FIR';
            app.BandpassIIRButton = uibutton(app.UIFigure, 'push');
            app.BandpassIIRButton.ButtonPushedFcn = createCallbackFcn(app, @BandpassIIRButtonPushed, true);
            app.BandpassIIRButton.Text = 'Bandpass IIR';
            app.LowpassFIRButton_2 = uibutton(app.UIFigure, 'push');
            app.LowpassFIRButton_2.ButtonPushedFcn = createCallbackFcn(app, @LowpassFIRButton_2Pushed, true);
            app.LowpassFIRButton_2.Text = 'Lowpass FIR';
            app.LowpassIIRButton_2 = uibutton(app.UIFigure, 'push');
            app.LowpassIIRButton_2.ButtonPushedFcn = createCallbackFcn(app, @LowpassIIRButton_2Pushed, true);
            app.LowpassIIRButton_2.Text = 'Lowpass IIR';
            app.HighpassFIRButton_2 = uibutton(app.UIFigure, 'push');
            app.HighpassFIRButton_2.ButtonPushedFcn = createCallbackFcn(app, @HighpassFIRButton_2Pushed, true);
            app.HighpassFIRButton_2.Text = 'Highpass FIR';
            app.HighpassIIRButton_2 = uibutton(app.UIFigure, 'push');
            app.HighpassIIRButton_2.ButtonPushedFcn = createCallbackFcn(app, @HighpassIIRButton_2Pushed, true);
            app.HighpassIIRButton_2.Text = 'Highpass IIR';
            app.BandpassFIRButton_2 = uibutton(app.UIFigure, 'push');
            app.BandpassFIRButton_2.ButtonPushedFcn = createCallbackFcn(app, @BandpassFIRButton_2Pushed, true);
            app.BandpassFIRButton_2.Text = 'Bandpass FIR';
            app.BandpassIIRButton_2 = uibutton(app.UIFigure, 'push');
            app.BandpassIIRButton_2.ButtonPushedFcn = createCallbackFcn(app, @BandpassIIRButton_2Pushed, true);
            app.BandpassIIRButton_2.Text = 'Bandpass IIR';
            app.soundButton_2 = uibutton(app.UIFigure, 'push');
            app.soundButton_2.ButtonPushedFcn = createCallbackFcn(app, @soundButton_2Pushed, true);
            app.soundButton_2.Text = 'sound';
            app.soundButton_3 = uibutton(app.UIFigure, 'push');
            app.soundButton_3.ButtonPushedFcn = createCallbackFcn(app, @soundButton_3Pushed, true);
            app.soundButton_3.Text = 'sound';
            app.soundButton_4 = uibutton(app.UIFigure, 'push');
            app.soundButton_4.ButtonPushedFcn = createCallbackFcn(app, @soundButton_4Pushed, true);
            app.soundButton_4.Text = 'sound';
            app.soundButton_5 = uibutton(app.UIFigure, 'push');
            app.soundButton_5.ButtonPushedFcn = createCallbackFcn(app, @soundButton_5Pushed, true);
            app.soundButton_5.Text = 'sound';
            app.soundButton_6 = uibutton(app.UIFigure, 'push');
            app.soundButton_6.ButtonPushedFcn = createCallbackFcn(app, @soundButton_6Pushed, true);
            app.soundButton_6.Text = 'sound';
            app.soundButton_7 = uibutton(app.UIFigure, 'push');
            app.soundButton_7.ButtonPushedFcn = createCallbackFcn(app, @soundButton_7Pushed, true);
            app.soundButton_7.Text = 'sound';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = speechDSP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end